classdef Blood_pressure_indicator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NoteTextArea                 matlab.ui.control.TextArea
        NoteLabel                    matlab.ui.control.Label
        BloodPressureEditField       matlab.ui.control.NumericEditField
        BloodPressureEditFieldLabel  matlab.ui.control.Label
        DoneButton                   matlab.ui.control.Button
        InformationEditField         matlab.ui.control.EditField
        InformationEditFieldLabel    matlab.ui.control.Label
        IndicatorLamp                matlab.ui.control.Lamp
        IndicatorLampLabel           matlab.ui.control.Label
        AgeGroupDropDown             matlab.ui.control.DropDown
        AgeGroupDropDownLabel        matlab.ui.control.Label
        BLOODPRESSUREINDICATORLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: InformationEditField
        function InformationEditFieldValueChanging(app, event)
                %if app.AgeGroupDropDown.Value == "Newborns" && app.BloodPressuremmHgDropDown.Value == "60-90"
                   % app.InformationEditField = "Blood Pressure Normal"
                  %  app.IndicatorLamp = [0.035 0.533 0.78]
               % else
                %    app.InformationEditField = "Not in Database"
              %  end
        end

        % Value changed function: AgeGroupDropDown
        function AgeGroupDropDownValueChanged(app, event)
            
            
        end

        % Value changed function: InformationEditField
        function InformationEditFieldValueChanged(app, event)

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)

            blood_pressure = app.BloodPressureEditField.Value;
            age_group = app.AgeGroupDropDown.Value;

            if (59<blood_pressure) && (blood_pressure<91) && age_group == "Newborns"
                app.InformationEditField.Value = "Normal Blood Pressure for a Newborn";
                app.IndicatorLamp.Color = [0 0 1];
            elseif (86<blood_pressure) && (blood_pressure<106) && age_group == "Infant"
                app.InformationEditField.Value = "Normal Blood Pressure for Infants";
                app.IndicatorLamp.Color = [0 0 1];
            elseif  (94<blood_pressure) && (blood_pressure<106) && age_group == "Toddler"
                app.InformationEditField.Value = "Normal Blood Pressure for Toddlers";
                app.IndicatorLamp.Color = [0 0 1];
            elseif  (94<blood_pressure) && (blood_pressure<111) && age_group == "Preschooler"
                app.InformationEditField.Value = "Normal Blood Pressure for Preschooler";
                app.IndicatorLamp.Color = [0 0 1];
            elseif (96<blood_pressure) && (blood_pressure<113) && age_group == "School-aged Child"
                app.InformationEditField.Value = "Normal Blood Pressure for a School-aged Child";
                app.IndicatorLamp.Color = [0 0 1];
            elseif  (111<blood_pressure) && (blood_pressure<129) && age_group == "Adolescent"
                app.InformationEditField.Value = "Normal Blood Pressure for Adolescents";
                app.IndicatorLamp.Color = [0 0 1];
            else
                app.InformationEditField.Value = "Abnormal Blood Pressure for Age group";
                app.IndicatorLamp.Color = [1 0 0];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BLOODPRESSUREINDICATORLabel = uilabel(app.UIFigure);
            app.BLOODPRESSUREINDICATORLabel.Text = 'BLOOD PRESSURE INDICATOR';
            app.AgeGroupDropDownLabel = uilabel(app.UIFigure);
            app.AgeGroupDropDownLabel.Text = 'Age Group';
            app.AgeGroupDropDown = uidropdown(app.UIFigure);
            app.AgeGroupDropDown.Items = {'Newborns', 'Infant', 'Toddler', 'Preschooler', 'School-aged Child', 'Adolescent'};
            app.AgeGroupDropDown.ValueChangedFcn = createCallbackFcn(app, @AgeGroupDropDownValueChanged, true);
            app.AgeGroupDropDown.Value = 'Newborns';
            app.IndicatorLampLabel = uilabel(app.UIFigure);
            app.IndicatorLampLabel.Text = 'Indicator';
            app.IndicatorLamp = uilamp(app.UIFigure);
            app.InformationEditFieldLabel = uilabel(app.UIFigure);
            app.InformationEditFieldLabel.Text = 'Information:';
            app.InformationEditField = uieditfield(app.UIFigure, 'text');
            app.InformationEditField.ValueChangedFcn = createCallbackFcn(app, @InformationEditFieldValueChanged, true);
            app.InformationEditField.ValueChangingFcn = createCallbackFcn(app, @InformationEditFieldValueChanging, true);
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.BloodPressureEditFieldLabel = uilabel(app.UIFigure);
            app.BloodPressureEditFieldLabel.Text = 'Blood Pressure';
            app.BloodPressureEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoteLabel = uilabel(app.UIFigure);
            app.NoteLabel.Text = 'Note:';
            app.NoteTextArea = uitextarea(app.UIFigure);
            app.NoteTextArea.Value = {'Blue indicates Normal Blood Pressure'; 'Red indicates Abnormal Blood Pressure'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Blood_pressure_indicator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end